classdef CarConversionMethodology_Performance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModeSwitch                      matlab.ui.control.Switch
        ModeLabel                       matlab.ui.control.Label
        SpecImage                       matlab.ui.control.Image
        ModelShowSpec                   matlab.ui.control.Button
        AutomaticmotorselectionPanel_2  matlab.ui.container.Panel
        AutoEVVoltage                   matlab.ui.control.EditField
        RunOtomatis                     matlab.ui.control.Button
        AutoShowSpec                    matlab.ui.control.Button
        AutoEVCurrent                   matlab.ui.control.EditField
        MotorDropDownLabel_12           matlab.ui.control.Label
        MotorDropDownLabel_11           matlab.ui.control.Label
        AutoEVMotor                     matlab.ui.control.EditField
        AutoEVPower                     matlab.ui.control.EditField
        MotorDropDownLabel_5            matlab.ui.control.Label
        MotorDropDownLabel_4            matlab.ui.control.Label
        UnitDropDown_2                  matlab.ui.control.DropDown
        UnitDropDown_2Label             matlab.ui.control.Label
        BatteryPlanPanel                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        PackPowerCapcity                matlab.ui.control.EditField
        MotorDropDownLabel_7            matlab.ui.control.Label
        MotorDropDownLabel_10           matlab.ui.control.Label
        BatteryShowSpec                 matlab.ui.control.Button
        PackDischarge                   matlab.ui.control.EditField
        CellDischarge                   matlab.ui.control.EditField
        ModulePerPackType               matlab.ui.control.DropDown
        CellPerModuleType               matlab.ui.control.DropDown
        ModulePerPack                   matlab.ui.control.Spinner
        CellPerModule                   matlab.ui.control.Spinner
        PackCapacity                    matlab.ui.control.EditField
        PackVoltage                     matlab.ui.control.EditField
        ModuleCapacity                  matlab.ui.control.EditField
        ModuleVoltage                   matlab.ui.control.EditField
        CellCapacity                    matlab.ui.control.EditField
        CellVoltage                     matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        ModelDropDownLabel_6            matlab.ui.control.Label
        PackMass                        matlab.ui.control.EditField
        PackmassEditFieldLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ModelDropDownLabel_2            matlab.ui.control.Label
        UnitDropDown                    matlab.ui.control.DropDown
        UnitLabel                       matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        ManualmotorselectionPanel       matlab.ui.container.Panel
        MotorDropDownLabel_9            matlab.ui.control.Label
        EVVoltage                       matlab.ui.control.DropDown
        EVShowSpec                      matlab.ui.control.Button
        EVPeakCurrent                   matlab.ui.control.EditField
        MotorDropDownLabel_8            matlab.ui.control.Label
        EVPeakPower                     matlab.ui.control.EditField
        MotorDropDownLabel_2            matlab.ui.control.Label
        EVMotor                         matlab.ui.control.DropDown
        MotorDropDownLabel              matlab.ui.control.Label
        RunManual                       matlab.ui.control.Button
        CostestimationPanel             matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TabelBiaya                      matlab.ui.control.Table
        CarConversionLabel              matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        constants
        torques_ice
        torques_bev
        motor_bev
        battery_constants
        model_name
        battery_count
        additionals
        battery_specs
        
        
        ev_name
        ev_type
        ev_voltage
        ev_power = 0
        ev_current = 0


        cell_mass = 0
        cell_voltage = 0
        cell_discharge = 0
        cell_capacity = 0

        module_mass = 0
        module_voltage = 0
        module_capacity = 0

        pack_mass = 0
        pack_voltage = 0
        pack_capacity = 0
        pack_power_capacity = 0
        pack_discharge_current = 0

        driving_cycle
        Driving_cycle_WLTP_Class3b_version5

        g       % Gravity
        e_t     % Transmission efficiency
        rho     % Air Density
        C_k     % 
        C_a     % Acceleration Coeficient
        C_j     % Asphalt Coefficient
        f_o     % Tire pressure 36 psi
        f_s     % Tire pressure 36 psi
        m_d     % Driver Mass
        m_a     % Supporting Component Mass
        e_c     % Energy Coefficient

        v_max_ice = 0
        v_max_bev = 0
        a_max_ice = 0
        a_max_bev = 0
        theta_max_ice = 0
        theta_max_bev = 0

        base_formatter = java.text.DecimalFormat;
        spec_path;
    end

    methods (Access = private)
        function currency = num2cur(app, x)
            currency = append('Rp. ', char(app.base_formatter.format(x)));
        end

        function value = get_model_const(app, const_name)
            value = app.constants{app.model_name, const_name};
        end

        function value = get_motor_const(app, const_name)
            value = app.motor_bev{app.ev_type, const_name};
        end

        function value = get_battery_const(app, const_name)
            value = app.battery_constants{"Li-ion", const_name};
        end

        function i_t = i_t(app)
            transmissions = endsWith( ...
                app.constants.Properties.VariableNames, ...
                "Transmission Ratio" ...
            );
            i_t = rmmissing(app.get_model_const(transmissions));
        end

        function mass = curb_mass_ice(app)
            mass = app.get_model_const("Curb Mass");         % Massa kendaraan tanpa pengendara (kg)
        end

        function mass = curb_mass_bev(app)
            m_e = app.get_model_const("Engine Mass");       % Massa mesin ICE
            m_f = app.get_model_const("Fuel Tank Mass");    % Massa tangki bensin ICE
            m_b = app.pack_mass;                            % Massa per pak baterai
            m_m = app.get_motor_const("Mass (kg)");         % Massa motor BEV
            m_a = app.m_a;
            m_bev = - m_e - m_f + m_b + m_m + m_a;

            mass = app.curb_mass_ice() + m_bev;
        end

        function R = C_r(app, V)
            % V: Kecepatan Kendaraan (m/s)

            c_k = app.C_k;  % Ban dengan kembangan
            c_a = app.C_a;  % Kecepatan konstan
            c_j = app.C_j;  % Jalan aspal
            f_o_ = app.f_o; % Tekanan ban 36psi
            f_s_ = app.f_s; % Tekanan ban 36psi

            R = c_k * c_a * c_j * (f_o_ + f_s_ * (V / 100) .^ 2.5);
        end

        function V = V_k(app, N)
            % N: Putaran motor (RPM)

            R = app.get_model_const("Tire Radius");     % Radius kerja roda penggerak (m)
            i_g = app.get_model_const("Axle Ratio");    % Rasio gardan
            i_t = app.i_t();                            % Rasio transmisi

            V = 2 * 3.14 * R * N ./ (60 * i_t' * i_g) * 3.6;
        end

        function F = F_t(app, T)
            % T: Torsi penggerak (Nm)
            % i_t: Rasio transmisi

            e_t_ = app.e_t;                                     % Efisiensi transmisi
            R = app.get_model_const("Tire Radius");             % Radius kerja roda penggerak (m)
            i_g = app.get_model_const("Axle Ratio");            % Rasio gardan
            i_t = app.i_t();                            % Rasio transmisi

            F = (i_t' * i_g * T * e_t_ / R) * 10 .^ -3;
        end

        function R = R_a(app, V)
            % V: Kecepatan Kendaraan (m/s)

            rho_ = app.rho; % Densitas udara
            C_d = app.get_model_const("Drag Coefficient");  % Koefisien hambat aerodinamis
            A = app.get_model_const("Frontal Area");        % Luas frontal (m^2)

            R = (1/2) * C_d * rho_ * A * V .^ 2 * 10 .^ -3;
        end

        function R = R_g(app, theta)
            % theta: Sudut tanjak (derajat)

            m_d_ = app.m_d;                         % Massa pengendara
            g_ = app.g;                             % Percepatan gravitasi
            m_v = app.get_model_const("Curb Mass");      % Massa kendaraan tanpa pengendara (kg)
            K = app.get_model_const("Passenger");    % Jumlah pengendara/kapasitas kursi mobil

            R = (m_v + K * m_d_) * g_ * sind(theta) * 10 .^ -3;
        end

        function R = R_g_bev(app, theta)
            % theta: Sudut tanjak (derajat)

            m_d_ = app.m_d;                                 % Massa pengendara
            g_ = app.g;                                     % Percepatan gravitasi
            m_v = app.get_model_const("Curb Mass");         % Massa kendaraan tanpa pengendara (kg)
            K = app.get_model_const("Passenger");           % Jumlah pengendara/kapasitas kursi mobil
            m_e = app.get_model_const("Engine Mass");       % Massa mesin ICE
            m_f = app.get_model_const("Fuel Tank Mass");    % Massa tangki bensin ICE
            m_b = app.pack_mass;                            % Massa per pak baterai
            m_m = app.get_motor_const("Mass (kg)");        % Massa motor BEV
            m_a = app.m_a;

            m_bev = - m_e - m_f + m_b + m_m + m_a;
            R = (m_v + K * m_d_ + m_bev) * g_ * sind(theta) * 10 .^ -3;
        end

        function R = R_r(app, V, theta)
            % V: Kecepatan Kendaraan (m/s)
            % theta: Sudut tanjak (derajat)

            c_r = app.C_r(V);   % Koefisien tahan gelinding
            m_d_ = app.m_d;     % Massa pengendara
            g_ = app.g;         % Percepatan gravitasi
            m_v = app.get_model_const("Curb Mass");      % Massa kendaraan tanpa pengendara (kg)
            K = app.get_model_const("Passenger");    % Jumlah pengendara/kapasitas kursi mobil

            R = g_ * cosd(theta) * c_r * (m_v + K * m_d_) * 10 .^ -3;
        end

        function R = R_r_bev(app, V, theta)
            % V: Kecepatan Kendaraan (m/s)
            % theta: Sudut tanjak (derajat)

            c_r = app.C_r(V);   % Koefisien tahan gelinding
            m_d_ = app.m_d;     % Massa pengendara
            g_ = app.g;         % Percepatan gravitasi
            m_v = app.get_model_const("Curb Mass");         % Massa kendaraan tanpa pengendara (kg)
            K = app.get_model_const("Passenger");           % Jumlah pengendara/kapasitas kursi mobil
            m_e = app.get_model_const("Engine Mass");       % Massa mesin ICE
            m_f = app.get_model_const("Fuel Tank Mass");    % Massa tangki bensin ICE
            m_b = app.pack_mass                             % Massa per pak baterai
            m_m = app.get_motor_const("Mass (kg)");        % Massa motor BEV
            m_a = app.m_a;

            m_bev = - m_e - m_f + m_b + m_m + m_a;
            R = g_ * cosd(theta) * c_r * (m_v + K * m_d_ + m_bev) * 10 .^ -3;
        end

        function R = F_r(app, V, theta)
            % V: Kecepatan Kendaraan (m/s)
            % theta: Sudut tanjak (derajat)

            r_a = app.R_a(V);           % Gaya hambat aerodinamis
            r_g = app.R_g(theta);       % Gaya hambat gradien
            r_r = app.R_r(V, theta);    % Gaya hambat gelinding

            R = r_a + r_g + r_r;
        end

        function R = F_r_bev(app, V, theta)
            % V: Kecepatan Kendaraan (m/s)
            % theta: Sudut tanjak (derajat)

            r_a = app.R_a(V);           % Gaya hambat aerodinamis
            r_g = app.R_g_bev(theta);       % Gaya hambat gradien
            r_r = app.R_r_bev(V, theta);    % Gaya hambat gelinding

            R = r_a + r_g + r_r;
        end

        function d = S(app)
            % C_d: Koefisien hambat aerodinamis
            % A: Luas frontal (m^2)
            % m_v: Massa kendaraan tanpa pengendara (kg)
            % K: Jumlah pengendara/kapasitas kursi mobil

            c = app.battery_count;                              % Jumlah pak baterai yang digunakan
            W = app.pack_power_capacity;                              % Kapasitas pak baterai (kWh)
            F_r50 = app.F_r_bev(50, 0);                         % Gaya hambat total pada jalan datar dan kecepatan 60 km/h (kN) BEV
            e_c_ = app.e_c;                                     % Efisiensi konsumsi energi

            d = (c * W * 3.6 * e_c_)/ F_r50;
        end
        
        function a = a(app, F_t, F_r)
            % a: Acceleration (ICE)
            % F_t: Force from Torque (ICE)
            % F_r: Resistance Torque (ICE)

            m_v = app.get_model_const("Curb Mass");      % Massa kendaraan tanpa pengendara (kg)
            m_d_ = app.m_d;                             % Massa pengendara
            K = app.get_model_const("Passenger");       % Jumlah pengendara/kapasitas kursi mobil

            a = (F_t - F_r) ./ (m_v + K * m_d_) * 10 .^ 3;
        end

        function a = a_bev(app, F_t, F_r)
            % a: Acceleration (ICE)
            % F_t: Force from Torque (ICE)
            % F_r: Resistance Torque (ICE)

            m_v = app.get_model_const("Curb Mass");         % Massa kendaraan tanpa pengendara (kg)
            m_d_ = app.m_d;                                 % Massa pengendara
            K = app.get_model_const("Passenger");           % Jumlah pengendara/kapasitas kursi mobil
            m_e = app.get_model_const("Engine Mass");       % Massa mesin ICE
            m_f = app.get_model_const("Fuel Tank Mass");    % Massa tangki bensin ICE
            m_b = app.pack_mass;                            % Massa per pak baterai
            m_m = app.get_motor_const("Mass (kg)");         % Massa motor BEV
            m_a = app.m_a;

            m_bev = - m_e - m_f + m_b + m_m + m_a;
            a = (F_t - F_r) ./ (m_v + K * m_d_ + m_bev) * 10 .^ 3;
        end

        function theta = theta_ice(app, V, F)
            % theta: Tilt Angle
            % V: Velocity / Speed
            % F: Force from Torque (ICE)

            rho_ = app.rho;                                 % Densitas udara
            C_d = app.get_model_const("Drag Coefficient");  % Koefisien hambat aerodinamis
            A = app.get_model_const("Frontal Area");        % Luas frontal (m^2)
            m_v = app.get_model_const("Curb Mass");         % Massa kendaraan tanpa pengendara (kg)
            m_d_ = app.m_d;                                 % Massa pengendara
            K = app.get_model_const("Passenger");           % Jumlah pengendara/kapasitas kursi mobil
            g_ = app.g;         % Percepatan gravitasi
            c_r = app.C_r(V);   % Koefisien tahan gelinding

            syms th
            R_a = (1/2) * C_d * rho_ * A * V .^ 2 * 10 .^ -3;
            R_g = (m_v + K * m_d_) * g_ * sind(th) * 10 .^ -3;
            R_r = g_ * cosd(th) * c_r * (m_v + K * m_d_) * 10 .^ -3;

            Fr = R_a + R_g + R_r;
            theta = solve(Fr == F / 1000, th);
            theta = real(double(min(theta)));
        end
        function theta = theta_bev(app, V, F)
            % theta: Tilt Angle
            % V: Velocity / Speed
            % F: Force from Torque (BEV)

            rho_ = app.rho;                                 % Densitas udara
            C_d = app.get_model_const("Drag Coefficient");  % Koefisien hambat aerodinamis
            A = app.get_model_const("Frontal Area");        % Luas frontal (m^2)
            m_v = app.get_model_const("Curb Mass");         % Massa kendaraan tanpa pengendara (kg)
            K = app.get_model_const("Passenger");           % Jumlah pengendara/kapasitas kursi mobil
            m_e = app.get_model_const("Engine Mass");       % Massa mesin ICE
            m_f = app.get_model_const("Fuel Tank Mass");    % Massa tangki bensin ICE
            m_b = app.pack_mass;                            % Massa per pak baterai
            m_m = app.get_motor_const("Mass (kg)");         % Massa motor BEV
            m_d_ = app.m_d;                                 % Massa pengendara
            g_ = app.g;         % Percepatan gravitasi
            c_r = app.C_r(V);   % Koefisien tahan gelinding
            m_a = app.m_a;
            m_bev = - m_e - m_f + m_b + m_m + m_a;

            syms th
            R_a = (1/2) * C_d * rho_ * A * V .^ 2 * 10 .^ -3;
            R_g = (m_v + K * m_d_ + m_bev) * g_ * sind(th) * 10 .^ -3;
            R_r = g_ * cosd(th) * c_r * (m_v + K * m_d_ + m_bev) * 10 .^ -3;

            Fr = R_a + R_g + R_r;
            theta = solve(Fr == F / 1000, th);
            theta = real(double(min(theta)));
        end

        function plt = plot_F_r(~, axes, V, F_r)
            plt = plot( ...
                axes, ...
                V, ...
                F_r, ...
                "LineStyle", "--", ...
                "LineWidth", 2);
            hold(axes, "on");
        end

        function plt = plot_F_t(~, axes, V, F_t)
            plt = plot( ...
                axes, ...
                V' * 3.6, ...
                F_t', ...
                "LineStyle", "-", ...
                "LineWidth", 2);
            hold(axes, "off");
        end

        function plt = plot_a(~, axes, V, a)
            plt = plot( ...
                axes, ...
                V' * 3.6, ...
                a', ...
                "LineStyle", "-", ...
                "LineWidth", 2);
            hold(axes, "on");
        end

        function draw_F_r_text(~, axes, plt, F_r)
            theta = 0:5:30;
            for i = 1:7
                text( ...
                    axes, ...
                    200, ...
                    max(F_r(i,:)) + 0.01, ...
                    num2str(theta(i)) + "^o", ...
                    "HorizontalAlignment","right", ...
                    "VerticalAlignment", "bottom", ...
                    "FontSize", 18, ...
                    "Color", plt(i).Color);
            end
        end

        function draw_F_t_text(~, axes, plt, V, F_t)
            for i = 1:size(F_t, 1)
                V_ = V(i,:) .* ~isnan(F_t(i,:)) .* 3.6;
                V_ = V_(V_ < 200);
                text( ...
                    axes, ...
                    max(V_), ...
                    F_t(i, V_ == max(V_)) ...
                    + 0.1, ...
                    "i_" + string(i), ...
                    "HorizontalAlignment","right", ...
                    "VerticalAlignment", "bottom", "FontSize", 18, ...
                    "Color", plt(i).Color);
            end
        end

        function draw_a_text(~, axes, plt, V, a)
            a(isnan(a))=0;
            for i = 1:size(a, 1)
                V_ = V(i,:) .* (a(i,:) > 0);

                if ~any(V_)
                    continue
                end

                text( ...
                    axes, ...
                    max(V_) * 3.6, ...
                    a(i, V_ == max(V_)) + 0.1, ...
                    "i_" + string(i), ...
                    "HorizontalAlignment","right", ...
                    "VerticalAlignment", "bottom", "FontSize", 18, ...
                    "Color", plt(i).Color);
            end
            hold(axes, "off");
        end

        function [v_max, a_max, theta_max] = find_max(app, V, F_t, F_r, a)
            n_trans = size(F_t, 1);
            V_kmh = 0:5:200;

            V_ = V(n_trans,~isnan(F_t(n_trans, :)));
            F_t_ = F_t(n_trans, ~isnan(F_t(n_trans, :)));
            x_bound = [V_kmh 1e+10 1e+10];
            y_bound = [F_r(1,:) F_r(end) 0];

            v_max = my_polyxpoly(V_ * 3.6, F_t_, x_bound, y_bound);
            if ~all(size(v_max))
                v_max = min([200, max(V_ * 3.6)]);
            end
            v_max = v_max(1);
            a_max = max(a(1,:));

            max_torque = max(F_t, [], 'all');
            speed = V(F_t == max_torque);
            theta_max = [speed(1), max_torque * 1000];
        end
        
        function draw_ice(app)
            % RESISTANCE
            V_kmh = 0:5:200;    % Km/hour
            V_ms = V_kmh / 3.6; % m/s
            theta = (0:5:30)';  % degree
            N = 0:500:8000;     % RPM

            T = app.torques_ice{app.model_name, string(N)};
            
            V_k = app.V_k(N) / 3.6;
            F_r = app.F_r(V_ms, theta);
            F_t = app.F_t(T);
            F_r0 = app.F_r(V_k, 0);
            a = app.a(F_t, F_r0);

            plt = app.plot_F_r(app.UIAxes, V_kmh, F_r);
            app.draw_F_r_text(app.UIAxes, plt, F_r);

            plt = app.plot_F_t(app.UIAxes, V_k, F_t);
            app.draw_F_t_text(app.UIAxes, plt, V_k, F_t);

            plt = app.plot_a(app.UIAxes_2, V_k, a);
            app.draw_a_text(app.UIAxes_2, plt, V_k, a);

            [app.v_max_ice, app.a_max_ice, theta_max] = app.find_max(V_k, F_t, F_r, a);
            app.theta_max_ice = app.theta_ice(theta_max(1), theta_max(2));
        end

        function draw_bev(app)
            % RESISTANCE
            V_kmh = 0:5:200;    % Km/hour
            V_ms = V_kmh / 3.6; % m/s
            theta = (0:5:30)';  % degree
            N = 0:500:10500;     % RPM

            T = app.torques_bev{app.ev_name, string(N)};

            F_r = app.F_r_bev(V_ms, theta);
            V_k = app.V_k(N) / 3.6;
            F_t = app.F_t(T);
            F_r0 = app.F_r_bev(V_k, 0);
            a = app.a_bev(F_t, F_r0);

            
            plt = app.plot_F_r(app.UIAxes_4, V_kmh, F_r);
            app.draw_F_r_text(app.UIAxes_4, plt, F_r);

            plt = app.plot_F_t(app.UIAxes_4, V_k, F_t);
            app.draw_F_t_text(app.UIAxes_4, plt, V_k, F_t);

            plt = app.plot_a(app.UIAxes_3, V_k, a);
            app.draw_a_text(app.UIAxes_3, plt, V_k, a);

            [app.v_max_bev, app.a_max_bev, theta_max] = app.find_max(V_k, F_t, F_r, a);
            app.theta_max_bev = app.theta_bev(theta_max(1), theta_max(2));
        end

        function isi_tabel_biaya(app)
            motor = app.get_motor_const("Price (IDR)");
            battery = app.get_battery_const("Price (IDR/kWh)") * app.pack_power_capacity;
            
            app.TabelBiaya.RowName = [
                "Motor";
                "Baterai";
                app.additionals.Properties.RowNames;
                "Total"
            ];
            
            total = motor + battery + sum(app.additionals{:,"Price (IDR)"});
            data = {app.num2cur(motor) app.num2cur(battery)};
            for idx = 1:length(app.additionals{:,"Price (IDR)"})
                data = {data{:} app.num2cur(app.additionals{idx, "Price (IDR)"})};
            end
            data = {data{:} app.num2cur(total)};
            app.TabelBiaya.Data = string(data)';
        end

        function S = S_ev_100(app)
            a = max((app.Driving_cycle_WLTP_Class3b_version5{3:end, :} - app.Driving_cycle_WLTP_Class3b_version5{1:end-2, :}) / (2 * 3.6), 0, 'includenan');
            V = app.Driving_cycle_WLTP_Class3b_version5{3:end, :} / 3.6;

            K = app.get_model_const("Passenger");   % Jumlah pengendara/kapasitas kursi mobil
            m_d_ = app.m_d;                         % Massa pengendara
            E_cycle_all = (app.R_a(V) * 10 ^ 3 + app.R_r_bev(V, 0) * 10 ^ 3 + (app.curb_mass_bev() + K * m_d_) * a) .* V * 1 / 3600 / 1000; % kWh
            E_cycle = [
                sum(E_cycle_all(:, 1:1), "all", "omitnan");
                sum(E_cycle_all(:, 1:2), "all", "omitnan");
                sum(E_cycle_all(:, 1:3), "all", "omitnan");
                sum(E_cycle_all(:, 1:4), "all", "omitnan");
            ];
            d = [
                app.driving_cycle{"Distance (m)", 1};
                sum(app.driving_cycle{"Distance (m)", 1:2});
                sum(app.driving_cycle{"Distance (m)", 1:3});
                sum(app.driving_cycle{"Distance (m)", 1:4});
            ];

            E_range = E_cycle ./ d;
            S = app.pack_power_capacity ./ E_range .* app.e_c;
        end

        function S = S_ev_60(app)
            S = app.S_ev_100() * 0.6;
        end

        function S = S_ev(app)
            S_ev_100 = app.S_ev_100();
            S_ev_60 = S_ev_100 * 0.6;
            S = [S_ev_100'; S_ev_60'];
        end

        function isi_tabel_driving_cycle(app)
            S = app.S_ev() / 1000;
            app.UITable2.Data = [
                num2str(app.driving_cycle{"Vavg (km/h)", 1}, "%.2f") + " km/h", num2str(app.driving_cycle{"Vmax (km/h)", 1}, "%.2f") + " km/h", num2str(S(1,1), "%.2f km"), num2str(S(2,1), "%.2f km");
                num2str(mean(app.driving_cycle{"Vavg (km/h)", 1:2}), "%.2f") + " km/h", num2str(max(app.driving_cycle{"Vmax (km/h)", 1:2}), "%.2f") + " km/h", num2str(S(1,2), "%.2f km"), num2str(S(2,2), "%.2f km");
                num2str(mean(app.driving_cycle{"Vavg (km/h)", 1:3}), "%.2f") + " km/h", num2str(max(app.driving_cycle{"Vmax (km/h)", 1:3}), "%.2f") + " km/h", num2str(S(1,3), "%.2f km"), num2str(S(2,3), "%.2f km");
                num2str(mean(app.driving_cycle{"Vavg (km/h)", 1:4}), "%.2f") + " km/h", num2str(max(app.driving_cycle{"Vmax (km/h)", 1:4}), "%.2f") + " km/h", num2str(S(1,4), "%.2f km"), num2str(S(2,4), "%.2f km")
            ];
        end
        
        function show_spec(app)
            app.SpecImage.ImageSource = app.spec_path;
            app.SpecImage.Visible = "on";
            app.SpecImage.Enable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Database = load('Database.mat');
            app.constants = Database.Car_specs;
            app.torques_ice = Database.IC_engine_torques;
            app.battery_constants = Database.battery_specs;
            app.ModelDropDown.Items = app.torques_ice.Properties.RowNames;
            app.ModelDropDown.Value = app.ModelDropDown.Items{1};
            app.model_name = app.ModelDropDown.Value;


            app.motor_bev = Database.EV_motor_specs;
            app.torques_bev = Database.EV_motor_torques;
            app.EVMotor.Items = app.motor_bev.Properties.RowNames;
            app.EVMotor.Value = app.EVMotor.Items{1};

            
            assumes = Database.Constants_assumptions;
            app.g       = assumes{"Gravity (m/s^2)", :};
            app.e_t     = assumes{"Transmission Efficiency", :};
            app.rho     = assumes{"Air Density (kg/m^3)", :};
            app.C_k     = assumes{"Ck rolling coefficient", :};
            app.C_a     = assumes{"Ca rolling coefficient", :};
            app.C_j     = assumes{"Cj rolling coefficient", :};
            app.f_o     = assumes{"fo rolling coefficient", :};
            app.f_s     = assumes{"fs rolling coefficient", :};
            app.m_d     = assumes{"Passenger Mass (kg)", :};
            app.m_a     = assumes{"EV Auxiliary Mass (kg)", :};
            app.e_c     = assumes{"EV Energy Efficiency", :};

            app.additionals = Database.additional_costs;
            app.battery_specs = Database.battery_specs;

            app.cell_mass = app.battery_specs{"Li-ion", "Mass (kg)"};
            app.cell_voltage = app.battery_specs{"Li-ion", "Voltage (V)"};
            app.cell_capacity = app.battery_specs{"Li-ion", "Nominal Capacity (Ah)"};
            app.cell_discharge = app.battery_specs{"Li-ion", "Discharge (C)"};

            app.CellVoltage.Value = num2str(app.cell_voltage) + " V";
            app.CellCapacity.Value = num2str(app.cell_capacity) + " Ah";
            app.CellDischarge.Value = num2str(app.cell_discharge) + " C";

            app.driving_cycle = Database.Driving_cycle;
            app.Driving_cycle_WLTP_Class3b_version5 = Database.Driving_cycle_WLTP_Class3b_version5;

            app.UITable.RowStriping = "off";

            app.MotorChanged()
            app.BatteryChanged()
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            app.model_name = app.ModelDropDown.Value;
%             app.JaraktempuhBEVManual.Value = 0;
%             app.JaraktempuhBEVOtomatis.Value = 0;
%             app.AutoEVMotor.Value = '';
%             app.UITable.Data = [];
%             app.TabelBiaya.Data = {};
%             plot(app.UIAxes, [0,0]);
%             plot(app.UIAxes_2, [0,0]);
%             plot(app.UIAxes_3, [0,0]);
%             plot(app.UIAxes_4, [0,0]);
        end

        % Button pushed function: RunManual
        function RunManualPushed(app, event)
            app.model_name = app.ModelDropDown.Value;
            app.battery_count = app.ModulePerPack.Value;

            app.draw_ice();
            app.draw_bev();

            app.UITable.Data = [ ...
                num2str(app.curb_mass_ice(), "%.2f") + " kg", num2str(app.curb_mass_bev(), "%.2f") + " kg"; ...
                num2str(app.v_max_ice, "%.2f") + " km/h", num2str(app.v_max_bev, "%.2f") + " km/h"; ...
                num2str(app.a_max_ice, "%.2f") + " m/s^2", num2str(app.a_max_bev, "%.2f") + " m/s^2"; ...
                num2str(app.a_max_ice * 3.6, "%.2f") + " (km/h)/s", num2str(app.a_max_bev * 3.6, "%.2f") + " (km/h)/s"; ...
                num2str(app.theta_max_ice, "%.2f") + "°", num2str(app.theta_max_bev, "%.2f") + "°" ...
            ];

%             app.JaraktempuhBEVManual.Value = app.S();
            app.isi_tabel_biaya()
            app.isi_tabel_driving_cycle()
        end

        % Button pushed function: RunOtomatis
        function RunOtomatisPushed(app, event)
            app.model_name = app.ModelDropDown.Value;
            app.draw_ice();

            v_max = {'-', '-', inf};
            for motor_name_ = app.EVMotor.Items
                app.ev_type = motor_name_;

                for voltage = app.EVVoltage.Items
                    voltage_ = str2num(char(replace(voltage, " V", "")));
                    app.ev_name = motor_name_{1} + "(" + voltage_ + "V)";
    
                    app.draw_bev()
                    if app.v_max_bev <= v_max{3} && app.v_max_bev >= app.v_max_ice
                        v_max = {app.ev_type, voltage_, app.v_max_bev};
                    end
                end
            end

            app.ev_type = v_max{1};
            app.ev_voltage = v_max{2};
            app.ev_name = app.ev_type + "(" + app.ev_voltage + "V)";
            app.draw_bev();

            app.ev_power = app.torques_bev{app.ev_name, "Peak Power"};
            app.ev_current = app.ev_power / app.ev_voltage * 1000;

            app.AutoEVMotor.Value = char(app.ev_type);
            app.AutoEVVoltage.Value = char(num2str(app.ev_voltage) + " V");
            app.AutoEVPower.Value = char(num2str(app.ev_power) + " kW");
            app.AutoEVCurrent.Value = char(num2str(app.ev_current) + " A");

            app.UITable.Data = [ ...
                num2str(app.curb_mass_ice(), "%.2f") + " kg", num2str(app.curb_mass_bev(), "%.2f") + " kg"; ...
                num2str(app.v_max_ice, "%.2f") + " km/h", num2str(app.v_max_bev, "%.2f") + " km/h"; ...
                num2str(app.a_max_ice, "%.2f") + " m/s^2", num2str(app.a_max_bev, "%.2f") + " m/s^2"; ...
                num2str(app.a_max_ice * 3.6, "%.2f") + " (km/h)/s", num2str(app.a_max_bev * 3.6, "%.2f") + " (km/h)/s"; ...
                num2str(app.theta_max_ice, "%.2f") + "°", num2str(app.theta_max_bev, "%.2f") + "°" ...
            ];
            
%             app.JaraktempuhBEVOtomatis.Value = app.S();
            app.isi_tabel_biaya()
            app.isi_tabel_driving_cycle()
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            value = app.UnitDropDown.Value;
            app.UIAxes_2.YLabel.String = "a (" + value + ")";
            max_km_per_h = 60;
            multiplier = max_km_per_h / 3.6 / 10;
            if value == "(km/h)/s"
                app.UIAxes_2.YTick = app.UIAxes_2.YTick * multiplier;
                app.UIAxes_2.YTickLabel = app.UIAxes_2.YTick * 3.6;
                app.UIAxes_2.YLim = app.UIAxes_2.YLim * multiplier;
            else
                app.UIAxes_2.YTick = app.UIAxes_2.YTick / multiplier;
                app.UIAxes_2.YTickLabel = app.UIAxes_2.YTick;
                app.UIAxes_2.YLim = app.UIAxes_2.YLim / multiplier;
            end
        end

        % Value changed function: UnitDropDown_2
        function UnitDropDown_2ValueChanged(app, event)
            value = app.UnitDropDown_2.Value;
            app.UIAxes_3.YLabel.String = "a (" + value + ")";
            max_km_per_h = 60;
            multiplier = max_km_per_h / 3.6 / 10;
            if value == "(km/h)/s"
                app.UIAxes_3.YTick = app.UIAxes_3.YTick * multiplier;
                app.UIAxes_3.YTickLabel = app.UIAxes_3.YTick * 3.6;
                app.UIAxes_3.YLim = app.UIAxes_3.YLim * multiplier;
            else
                app.UIAxes_3.YTick = app.UIAxes_3.YTick / multiplier;
                app.UIAxes_3.YTickLabel = app.UIAxes_3.YTick;
                app.UIAxes_3.YLim = app.UIAxes_3.YLim / multiplier;
            end
        end

        % Value changed function: EVMotor, EVVoltage
        function MotorChanged(app, event)
            app.ev_type = app.EVMotor.Value;
            app.ev_name = app.ev_type + "(" + replace(app.EVVoltage.Value, " V", "") + "V)";
            
            app.ev_voltage = str2double(replace(app.EVVoltage.Value, " V", ""));
            app.ev_power = app.torques_bev{app.ev_name, "Peak Power"};
            app.ev_current = app.ev_power / app.ev_voltage * 1000;

            app.EVPeakPower.Value = num2str(app.ev_power) + " kW";
            app.EVPeakCurrent.Value = num2str(app.ev_current, 4) + " A";

            display(app.ev_name)
        end

        % Value changed function: CellPerModule, CellPerModuleType, 
        % ...and 2 other components
        function BatteryChanged(app, event)
            % Module
            cell_per_module_type = app.CellPerModuleType.Value;
            if cell_per_module_type == "Series"
                app.module_voltage = app.cell_voltage * app.CellPerModule.Value;
                app.module_capacity = app.cell_capacity;
            else
                app.module_voltage = app.cell_voltage;
                app.module_capacity = app.cell_capacity * app.CellPerModule.Value;
            end

            module_per_pack_type = app.ModulePerPackType.Value;
            if module_per_pack_type == "Series"
                app.pack_voltage = app.module_voltage * app.ModulePerPack.Value;
                app.pack_capacity = app.module_capacity;
            else
                app.pack_voltage = app.module_voltage;
                app.pack_capacity = app.module_capacity * app.ModulePerPack.Value;
            end

            app.pack_mass = app.cell_mass * app.CellPerModule.Value * app.ModulePerPack.Value;
            app.pack_power_capacity = app.pack_voltage * app.pack_capacity / 1000;
            app.pack_discharge_current = app.pack_capacity * app.cell_discharge;

            app.ModuleVoltage.Value = num2str(app.module_voltage) + " V";
            app.ModuleCapacity.Value = num2str(app.module_capacity) + " Ah";
            app.PackVoltage.Value = num2str(app.pack_voltage) + " V";
            app.PackCapacity.Value = num2str(app.pack_capacity) + " Ah";

            app.PackMass.Value = num2str(app.pack_mass) + " kg";
            app.PackPowerCapcity.Value = num2str(app.pack_power_capacity) + " kWh";
            app.PackDischarge.Value = num2str(app.pack_discharge_current) + " A";
        end

        % Button pushed function: ModelShowSpec
        function ModelShowSpecButtonPushed(app, event)
            app.spec_path = "Gambar/Specs " + app.model_name + ".jpg";
            app.show_spec()
        end

        % Button pushed function: EVShowSpec
        function EVShowSpecButtonPushed(app, event)
            app.spec_path = "Gambar/Specs " + app.ev_type + ".jpg";
            app.show_spec()
        end

        % Button pushed function: BatteryShowSpec
        function BatteryShowSpecButtonPushed(app, event)
            app.spec_path = "Gambar/Specs Battery Cell.jpg";
            app.show_spec()
        end

        % Button pushed function: AutoShowSpec
        function AutoShowSpecButtonPushed(app, event)
            app.spec_path = "Gambar/Specs " + app.AutoEVMotor.Value + ".jpg";
            app.show_spec()
        end

        % Image clicked function: SpecImage
        function SpecImageClicked(app, event)
            app.SpecImage.Visible = "off";
            app.SpecImage.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ICE Car Traction')
            xlabel(app.UIAxes, 'V (km/h)')
            ylabel(app.UIAxes, 'F (kN)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 200];
            app.UIAxes.YLim = [0 20];
            app.UIAxes.XTick = [0 20 40 60 80 100 120 140 160 180 200];
            app.UIAxes.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
            app.UIAxes.YTick = [0 2 4 6 8 10 12 14 16 18 20];
            app.UIAxes.YTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, ' ICE Car Acceleration')
            xlabel(app.UIAxes_2, 'V (km/h)')
            ylabel(app.UIAxes_2, 'a (m/s^2)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 200];
            app.UIAxes_2.YLim = [0 10];
            app.UIAxes_2.XTick = [0 20 40 60 80 100 120 140 160 180 200];
            app.UIAxes_2.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
            app.UIAxes_2.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_2.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, ' BEV Car Acceleration')
            xlabel(app.UIAxes_3, 'V (km/h)')
            ylabel(app.UIAxes_3, 'a (m/s^2)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [0 200];
            app.UIAxes_3.YLim = [0 10];
            app.UIAxes_3.XTick = [0 20 40 60 80 100 120 140 160 180 200];
            app.UIAxes_3.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
            app.UIAxes_3.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_3.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'BEV Car Traction')
            xlabel(app.UIAxes_4, 'V (km/h)')
            ylabel(app.UIAxes_4, 'F (kN)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XLim = [0 200];
            app.UIAxes_4.YLim = [0 20];
            app.UIAxes_4.XTick = [0 20 40 60 80 100 120 140 160 180 200];
            app.UIAxes_4.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
            app.UIAxes_4.YTick = [0 2 4 6 8 10 12 14 16 18 20];
            app.UIAxes_4.YTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'};
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'CHOOSE CAR MODEL :';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'Fiat Punto 2001', 'Toyota Calya 2019', 'Toyota Calya 2022', 'Toyota Fortuner 2012'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Fiat Punto 2001';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ICE'; 'BEV'};
            app.UITable.RowName = {'Curb mass'; 'Top speed'; 'Top acceleration'; 'Top acceleration'; 'Top gradeability (no slip assumption)'};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.CarConversionLabel = uilabel(app.UIFigure);
            app.CarConversionLabel.Text = 'Car Conversion';
            app.CostestimationPanel = uipanel(app.UIFigure);
            app.CostestimationPanel.Title = ' Cost estimation';
            app.GridLayout2 = uigridlayout(app.CostestimationPanel);
            app.TabelBiaya = uitable(app.GridLayout2);
            app.TabelBiaya.ColumnName = '';
            app.TabelBiaya.RowName = {'Motor'; 'Baterai'; 'Operasional'; 'Total'};
            app.TabelBiaya.ColumnSortable = true;
            app.TabelBiaya.ColumnEditable = true;
            app.ManualmotorselectionPanel = uipanel(app.UIFigure);
            app.ManualmotorselectionPanel.Title = ' Manual motor selection';
            app.RunManual = uibutton(app.ManualmotorselectionPanel, 'push');
            app.RunManual.ButtonPushedFcn = createCallbackFcn(app, @RunManualPushed, true);
            app.RunManual.Text = 'Run';
            app.MotorDropDownLabel = uilabel(app.ManualmotorselectionPanel);
            app.MotorDropDownLabel.Text = 'Choose motor:';
            app.EVMotor = uidropdown(app.ManualmotorselectionPanel);
            app.EVMotor.Items = {'HVH250-090 SOM', 'HVH250-090 DOM', 'HVH250-115 SOM', 'HVH250-115 DOM'};
            app.EVMotor.ValueChangedFcn = createCallbackFcn(app, @MotorChanged, true);
            app.EVMotor.Value = 'HVH250-090 SOM';
            app.MotorDropDownLabel_2 = uilabel(app.ManualmotorselectionPanel);
            app.MotorDropDownLabel_2.Text = 'Voltage:';
            app.EVPeakPower = uieditfield(app.ManualmotorselectionPanel, 'text');
            app.EVPeakPower.Editable = 'off';
            app.MotorDropDownLabel_8 = uilabel(app.ManualmotorselectionPanel);
            app.MotorDropDownLabel_8.Text = 'Peak power:';
            app.EVPeakCurrent = uieditfield(app.ManualmotorselectionPanel, 'text');
            app.EVPeakCurrent.Editable = 'off';
            app.EVShowSpec = uibutton(app.ManualmotorselectionPanel, 'push');
            app.EVShowSpec.ButtonPushedFcn = createCallbackFcn(app, @EVShowSpecButtonPushed, true);
            app.EVShowSpec.Text = 'See specs';
            app.EVVoltage = uidropdown(app.ManualmotorselectionPanel);
            app.EVVoltage.Items = {'350 V', '500 V', '600 V', '700 V'};
            app.EVVoltage.ValueChangedFcn = createCallbackFcn(app, @MotorChanged, true);
            app.EVVoltage.Value = '350 V';
            app.MotorDropDownLabel_9 = uilabel(app.ManualmotorselectionPanel);
            app.MotorDropDownLabel_9.Text = 'Peak current:';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Vavg'; 'Vmax'; 'EV range (100%)'; 'EV range (60%)'};
            app.UITable2.RowName = {'City slow'; 'City speed'; 'Motorway'; 'Sport'};
            app.UnitLabel = uilabel(app.UIFigure);
            app.UnitLabel.Text = 'Unit :';
            app.UnitDropDown = uidropdown(app.UIFigure);
            app.UnitDropDown.Items = {'m/s^2', '(km/h)/s'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = 'm/s^2';
            app.BatteryPlanPanel = uipanel(app.UIFigure);
            app.BatteryPlanPanel.Title = ' Battery Plan';
            app.GridLayout3 = uigridlayout(app.BatteryPlanPanel);
            app.ModelDropDownLabel_2 = uilabel(app.GridLayout3);
            app.ModelDropDownLabel_2.Text = '#Cell/module:';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = 'Module';
            app.PackmassEditFieldLabel = uilabel(app.GridLayout3);
            app.PackmassEditFieldLabel.Text = 'Pack';
            app.PackMass = uieditfield(app.GridLayout3, 'text');
            app.PackMass.Editable = 'off';
            app.PackMass.Value = '0 kg';
            app.ModelDropDownLabel_6 = uilabel(app.GridLayout3);
            app.ModelDropDownLabel_6.Text = 'Cell';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '#Module:';
            app.CellVoltage = uieditfield(app.GridLayout3, 'text');
            app.CellVoltage.Editable = 'off';
            app.CellCapacity = uieditfield(app.GridLayout3, 'text');
            app.CellCapacity.Editable = 'off';
            app.ModuleVoltage = uieditfield(app.GridLayout3, 'text');
            app.ModuleVoltage.Editable = 'off';
            app.ModuleCapacity = uieditfield(app.GridLayout3, 'text');
            app.ModuleCapacity.Editable = 'off';
            app.PackVoltage = uieditfield(app.GridLayout3, 'text');
            app.PackVoltage.Editable = 'off';
            app.PackCapacity = uieditfield(app.GridLayout3, 'text');
            app.PackCapacity.Editable = 'off';
            app.CellPerModule = uispinner(app.GridLayout3);
            app.CellPerModule.Limits = [1 Inf];
            app.CellPerModule.ValueChangedFcn = createCallbackFcn(app, @BatteryChanged, true);
            app.CellPerModule.Value = 1;
            app.ModulePerPack = uispinner(app.GridLayout3);
            app.ModulePerPack.Limits = [1 Inf];
            app.ModulePerPack.ValueDisplayFormat = '%2g';
            app.ModulePerPack.ValueChangedFcn = createCallbackFcn(app, @BatteryChanged, true);
            app.ModulePerPack.Value = 1;
            app.CellPerModuleType = uidropdown(app.GridLayout3);
            app.CellPerModuleType.Items = {'Series', 'Parallel'};
            app.CellPerModuleType.ValueChangedFcn = createCallbackFcn(app, @BatteryChanged, true);
            app.CellPerModuleType.Value = 'Series';
            app.ModulePerPackType = uidropdown(app.GridLayout3);
            app.ModulePerPackType.Items = {'Series', 'Parallel'};
            app.ModulePerPackType.ValueChangedFcn = createCallbackFcn(app, @BatteryChanged, true);
            app.ModulePerPackType.Value = 'Series';
            app.CellDischarge = uieditfield(app.GridLayout3, 'text');
            app.CellDischarge.Editable = 'off';
            app.PackDischarge = uieditfield(app.GridLayout3, 'text');
            app.PackDischarge.Editable = 'off';
            app.BatteryShowSpec = uibutton(app.GridLayout3, 'push');
            app.BatteryShowSpec.ButtonPushedFcn = createCallbackFcn(app, @BatteryShowSpecButtonPushed, true);
            app.BatteryShowSpec.Text = 'See specs';
            app.MotorDropDownLabel_10 = uilabel(app.GridLayout3);
            app.MotorDropDownLabel_10.Text = 'Discharge rate:';
            app.MotorDropDownLabel_7 = uilabel(app.GridLayout3);
            app.MotorDropDownLabel_7.Text = 'Pack maximum continuous discharge current =';
            app.PackPowerCapcity = uieditfield(app.GridLayout3, 'text');
            app.PackPowerCapcity.Editable = 'off';
            app.PackPowerCapcity.Value = '0 kWh';
            app.UnitDropDown_2Label = uilabel(app.UIFigure);
            app.UnitDropDown_2Label.Text = 'Unit :';
            app.UnitDropDown_2 = uidropdown(app.UIFigure);
            app.UnitDropDown_2.Items = {'m/s^2', '(km/h)/s'};
            app.UnitDropDown_2.ValueChangedFcn = createCallbackFcn(app, @UnitDropDown_2ValueChanged, true);
            app.UnitDropDown_2.Value = 'm/s^2';
            app.AutomaticmotorselectionPanel_2 = uipanel(app.UIFigure);
            app.AutomaticmotorselectionPanel_2.Title = ' Automatic motor selection';
            app.MotorDropDownLabel_4 = uilabel(app.AutomaticmotorselectionPanel_2);
            app.MotorDropDownLabel_4.Text = 'Chosen motor:';
            app.MotorDropDownLabel_5 = uilabel(app.AutomaticmotorselectionPanel_2);
            app.MotorDropDownLabel_5.Text = 'Voltage:';
            app.AutoEVPower = uieditfield(app.AutomaticmotorselectionPanel_2, 'text');
            app.AutoEVPower.Editable = 'off';
            app.AutoEVMotor = uieditfield(app.AutomaticmotorselectionPanel_2, 'text');
            app.AutoEVMotor.Editable = 'off';
            app.MotorDropDownLabel_11 = uilabel(app.AutomaticmotorselectionPanel_2);
            app.MotorDropDownLabel_11.Text = 'Peak power:';
            app.MotorDropDownLabel_12 = uilabel(app.AutomaticmotorselectionPanel_2);
            app.MotorDropDownLabel_12.Text = 'Peak current:';
            app.AutoEVCurrent = uieditfield(app.AutomaticmotorselectionPanel_2, 'text');
            app.AutoEVCurrent.Editable = 'off';
            app.AutoShowSpec = uibutton(app.AutomaticmotorselectionPanel_2, 'push');
            app.AutoShowSpec.ButtonPushedFcn = createCallbackFcn(app, @AutoShowSpecButtonPushed, true);
            app.AutoShowSpec.Text = 'See specs';
            app.RunOtomatis = uibutton(app.AutomaticmotorselectionPanel_2, 'push');
            app.RunOtomatis.ButtonPushedFcn = createCallbackFcn(app, @RunOtomatisPushed, true);
            app.RunOtomatis.Text = 'Run';
            app.AutoEVVoltage = uieditfield(app.AutomaticmotorselectionPanel_2, 'text');
            app.AutoEVVoltage.Editable = 'off';
            app.ModelShowSpec = uibutton(app.UIFigure, 'push');
            app.ModelShowSpec.ButtonPushedFcn = createCallbackFcn(app, @ModelShowSpecButtonPushed, true);
            app.ModelShowSpec.Text = 'See specs';
            app.SpecImage = uiimage(app.UIFigure);
            app.SpecImage.ImageClickedFcn = createCallbackFcn(app, @SpecImageClicked, true);
            app.SpecImage.Enable = 'off';
            app.SpecImage.Visible = 'off';
            app.SpecImage.ImageSource = fullfile(pathToMLAPP, 'Gambar', 'Specs Fiat Punto 2001.jpg');
            app.ModeLabel = uilabel(app.UIFigure);
            app.ModeLabel.Text = 'Mode :';
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'manual', 'auto'};
            app.ModeSwitch.Value = 'manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarConversionMethodology_Performance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end